classdef UI_EyeTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AnalizzaTrendGruppoButton  matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Database_finale
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          dati_salvati = load('Database_eyetracking.mat');
            app.Database_finale = dati_salvati.Database_finale;
        end

        % Callback function: not associated with a component
        function AnalizzaTrendButtonPushed(app, event)

        end

        % Button pushed function: AnalizzaTrendGruppoButton
        function AnalizzaTrendGruppoButtonPushed(app, event)

    num_blocchi = 10;
    gruppi = unique(app.Database_finale.Gruppo_sano_patologico);
    gruppi = gruppi(~ismissing(gruppi));

    cla(app.UIAxes);
    hold(app.UIAxes, 'on');

    x_trend = 1:num_blocchi;
    legend_labels = {};

    for g = 1:numel(gruppi)

        gruppo_corrente = string(gruppi(g));

        % Filtro tutte le righe di questo gruppo
        Filtro_gruppo = string(app.Database_finale.Gruppo_sano_patologico) == gruppo_corrente;
        Dati_Gruppo = app.Database_finale(Filtro_gruppo, :);

        pazienti_gruppo = unique(Dati_Gruppo.ID_Paziente);
        matrice_trend = nan(numel(pazienti_gruppo), num_blocchi);

        % Per ogni paziente del gruppo, calcolo i 10 blocchi cronologici
        for p = 1:numel(pazienti_gruppo)
            Filtro_paziente = string(Dati_Gruppo.ID_Paziente) == string(pazienti_gruppo(p));
            Dati_Paziente = Dati_Gruppo(Filtro_paziente, :);
            Dati_Paziente = sortrows(Dati_Paziente, 'ID_Acquisizione');

            latenza = Dati_Paziente.Latenza_ms;
            n_righe = height(Dati_Paziente);

            if n_righe < num_blocchi
                continue; % paziente con troppo pochi dati, lo salto
            end

            dim_blocco = floor(n_righe / num_blocchi);
            for i = 1:num_blocchi
                idx_start = (i - 1) * dim_blocco + 1;
                if i == num_blocchi
                    idx_end = n_righe;
                else
                    idx_end = i * dim_blocco;
                end
                matrice_trend(p, i) = mean(latenza(idx_start:idx_end), 'omitnan');
            end
        end

        % Media del gruppo, blocco per blocco (media tra tutti i pazienti)
        media_gruppo = mean(matrice_trend, 1, 'omitnan');

        % Colore fisso per gruppo, con un semplice if/elseif
        if gruppo_corrente == "Parkinson"
            colore = [0.8500 0.3250 0.0980];   % arancione
        elseif gruppo_corrente == "Alzheimer"
            colore = [0.4940 0.1840 0.5560];   % viola
        elseif gruppo_corrente == "Controllo"
            colore = [0 0.4470 0.7410];        % blu
        else
            colore = [0.4660 0.6740 0.1880];   % verde, per eventuali altri gruppi
        end

        plot(app.UIAxes, x_trend, media_gruppo, '-o', ...
             'LineWidth', 2.5, 'MarkerSize', 6, ...
             'MarkerFaceColor', colore, 'Color', colore);

        legend_labels{end+1} = sprintf('%s (n=%d)', gruppo_corrente, numel(pazienti_gruppo)); %#ok<AGROW>
    end

    hold(app.UIAxes, 'off');

    title(app.UIAxes, 'Confronto Trend Latenza tra Gruppi');
    xlabel(app.UIAxes, "Fasi del Test (10 Blocchi Cronologici)");
    ylabel(app.UIAxes, "Latenza Media (ms)");
    grid(app.UIAxes, 'on');
    app.UIAxes.XLim = [0.5, num_blocchi + 0.5];
    app.UIAxes.XTick = 1:num_blocchi;
    legend(app.UIAxes, legend_labels, 'Location', 'best');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TrendAxes')
            xlabel(app.UIAxes, 'ID Acquisizione')
            ylabel(app.UIAxes, 'Latenza ms')
            zlabel(app.UIAxes, 'Z')
            app.AnalizzaTrendGruppoButton = uibutton(app.UIFigure, 'push');
            app.AnalizzaTrendGruppoButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizzaTrendGruppoButtonPushed, true);
            app.AnalizzaTrendGruppoButton.Text = 'Confronta gruppi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_EyeTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end